classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DesignButton                 matlab.ui.control.Button
        ChooseyourmethodButtonGroup  matlab.ui.container.ButtonGroup
        WindowSincMethodButton       matlab.ui.control.RadioButton
        LSMethodButton               matlab.ui.control.RadioButton
        WLSMethodButton              matlab.ui.control.RadioButton
        FilterdesignerLabel          matlab.ui.control.Label
        CutOfffrequencyLabel         matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        FiltertypeLabel              matlab.ui.control.Label
        DropDown                     matlab.ui.control.DropDown
        WindowtypeLabel              matlab.ui.control.Label
        DropDown_2                   matlab.ui.control.DropDown
        EditField_2                  matlab.ui.control.NumericEditField
        FilterLengthevenvalueLabel   matlab.ui.control.Label
        WomegaLabel                  matlab.ui.control.Label
        EditField_3                  matlab.ui.control.EditField
        WeightsLabel_2               matlab.ui.control.Label
        EditField_4                  matlab.ui.control.EditField
        hdLabel                      matlab.ui.control.Label
        EditField_5                  matlab.ui.control.EditField
        ThesevaluesshouldbeenteredasfilecontainsMatrixLabel  matlab.ui.control.Label
        EditField_6                  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            if app.WindowSincMethodButton.Value
                if strcmp (app.DropDown.Value,'LowPass')
                    f=FIR(app.EditField_2.Value,app.EditField.Value,'l');
                elseif strcmp (app.DropDown.Value,'HighPass')
                     f=FIR(app.EditField_2.Value,app.EditField.Value,'h');
                elseif strcmp (app.DropDown.Value,'BandPass')
                     f=FIR(app.EditField_2.Value,[app.EditField.Value app.EditField_6.Value],'b');
                elseif strcmp (app.DropDown.Value,'StopPass')
                     f=FIR(app.EditField_2.Value,[app.EditField.Value app.EditField_6.Value],'s');
                end
                
                if strcmp (app.DropDown_2.Value,'Rect window')
                    w=rectwin(app.EditField_2.Value)'; 
                    f= f.*w;
                elseif strcmp (app.DropDown_2.Value,'Blackman window')
                    w=blackman(app.EditField_2.Value)'; 
                    f= f.*w;
                elseif strcmp (app.DropDown_2.Value,'Chebychev window')
                    w=chebwin(app.EditField_2.Value)'; 
                    f= f.*w;
                elseif strcmp (app.DropDown_2.Value,'Kaiser window')
                    w=kaiser(app.EditField_2.Value)'; 
                    f= f.*w;
                end
                F=abs(fft(f,2048));
                plot(app.UIAxes,F(1:length(F)/2));
                
            elseif app.LSMethodButton.Value
               W = importdata(app.EditField_3.Value);
               h = importdata(app.EditField_5.Value);
               
                f=LS_FIR(app.EditField_2.Value,W,h);
                y=abs(fft(f,2048));
        
                plot(app.UIAxes,y(1:length(y)/2))
                
            else
                W = importdata(app.EditField_3.Value);
                h = importdata(app.EditField_5.Value);
                Weights = importdata(app.EditField_4.Value);
                f=WLS_FIR(app.EditField_2.Value,W,h,Weights);
                 y=abs(fft(f,2048));
        
                plot(app.UIAxes,y(1:length(y)/2))
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.DesignButton = uibutton(app.UIFigure, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = 'Design';
            app.ChooseyourmethodButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseyourmethodButtonGroup.Title = 'Choose your method';
            app.WindowSincMethodButton = uiradiobutton(app.ChooseyourmethodButtonGroup);
            app.WindowSincMethodButton.Text = 'Window Sinc Method';
            app.WindowSincMethodButton.Value = true;
            app.LSMethodButton = uiradiobutton(app.ChooseyourmethodButtonGroup);
            app.LSMethodButton.Text = 'LS Method';
            app.WLSMethodButton = uiradiobutton(app.ChooseyourmethodButtonGroup);
            app.WLSMethodButton.Text = 'WLS Method';
            app.FilterdesignerLabel = uilabel(app.UIFigure);
            app.FilterdesignerLabel.Text = 'Filter designer';
            app.CutOfffrequencyLabel = uilabel(app.UIFigure);
            app.CutOfffrequencyLabel.Text = 'CutOff frequency';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FiltertypeLabel = uilabel(app.UIFigure);
            app.FiltertypeLabel.Text = 'Filter type';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {' ', 'LowPass', 'HighPass', 'BandPass', 'StopPass'};
            app.DropDown.Value = ' ';
            app.WindowtypeLabel = uilabel(app.UIFigure);
            app.WindowtypeLabel.Text = 'Window type';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'', 'Rect window', 'Blackman window', 'Chebychev window', 'Kaiser window'};
            app.DropDown_2.Value = 'Rect window';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FilterLengthevenvalueLabel = uilabel(app.UIFigure);
            app.FilterLengthevenvalueLabel.Text = 'Filter Length (even value)';
            app.WomegaLabel = uilabel(app.UIFigure);
            app.WomegaLabel.Text = 'W (omega)';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.WeightsLabel_2 = uilabel(app.UIFigure);
            app.WeightsLabel_2.Text = 'Weights ';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.hdLabel = uilabel(app.UIFigure);
            app.hdLabel.Text = 'hd';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.ThesevaluesshouldbeenteredasfilecontainsMatrixLabel = uilabel(app.UIFigure);
            app.ThesevaluesshouldbeenteredasfilecontainsMatrixLabel.Text = 'These values should be entered as file contains Matrix';
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end